classdef trainer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Motor_Prop_Calc                 matlab.ui.Figure
        Window1_header_name             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Step1InputsTab                  matlab.ui.container.Tab
        InputPlaneConditionsLabel       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        InputLimitingConditionsLabel    matlab.ui.control.Label
        MaxDiameterLabel                matlab.ui.control.Label
        MinimumDiameterLabel            matlab.ui.control.Label
        MaximumPitchLabel               matlab.ui.control.Label
        MinimumPitchLabel               matlab.ui.control.Label
        Window1_max_Diam                matlab.ui.control.NumericEditField
        Window1_min_Diam                matlab.ui.control.NumericEditField
        Window1_max_Pitch               matlab.ui.control.NumericEditField
        Window1_min_Pitch               matlab.ui.control.NumericEditField
        MaximumBatteryCellsLabel        matlab.ui.control.Label
        MinimumBatteryCellsLabel        matlab.ui.control.Label
        Window1_max_cell_count          matlab.ui.control.NumericEditField
        Window1_min_cell_count          matlab.ui.control.NumericEditField
        OnlyAvailablePropellersCheckBox  matlab.ui.control.CheckBox
        StepBatteryCellsLabel           matlab.ui.control.Label
        Window1_step_cell_count         matlab.ui.control.NumericEditField
        MaxMotorWeightLabel             matlab.ui.control.Label
        Window1_max_motor_weight        matlab.ui.control.NumericEditField
        MaxRotorSpeedLabel              matlab.ui.control.Label
        Window1_max_speed_rotation      matlab.ui.control.NumericEditField
        Window1_go_button               matlab.ui.control.Button
        Panel2                          matlab.ui.container.Panel
        KnownPlaneConditionsLabel       matlab.ui.control.Label
        CruiseSpeed_maxLabel            matlab.ui.control.Label
        CruiseSpeed_minLabel            matlab.ui.control.Label
        CruiseSpeed_stepLabel           matlab.ui.control.Label
        CruiseDrag_maxLabel             matlab.ui.control.Label
        CruiseDrag_minLabel             matlab.ui.control.Label
        CruiseDrag_stepLabel            matlab.ui.control.Label
        Mass_maxLabel                   matlab.ui.control.Label
        Mass_minLabel                   matlab.ui.control.Label
        Mass_stepLabel                  matlab.ui.control.Label
        ROC_maxLabel                    matlab.ui.control.Label
        ROC_minLabel                    matlab.ui.control.Label
        ROC_stepLabel                   matlab.ui.control.Label
        VelocityduringROC_maxLabel      matlab.ui.control.Label
        VelocityduringROC_minLabel      matlab.ui.control.Label
        VelocityduringROC_stepLabel     matlab.ui.control.Label
        DragduringROC_maxLabel          matlab.ui.control.Label
        DragduringROC_minLabel          matlab.ui.control.Label
        DragduringROC_stepLabel         matlab.ui.control.Label
        Window1_max_cruise_speed        matlab.ui.control.NumericEditField
        Window1_min_cruise_speed        matlab.ui.control.NumericEditField
        Window1_step_cruise_speed       matlab.ui.control.NumericEditField
        Window1_max_cruise_thrust       matlab.ui.control.NumericEditField
        Window1_min_cruise_thrust       matlab.ui.control.NumericEditField
        Window1_step_cruise_thrust      matlab.ui.control.NumericEditField
        Window1_max_mass                matlab.ui.control.NumericEditField
        Window1_min_mass                matlab.ui.control.NumericEditField
        Window1_step_mass               matlab.ui.control.NumericEditField
        Window1_max_ROC                 matlab.ui.control.NumericEditField
        Window1_min_ROC                 matlab.ui.control.NumericEditField
        Window1_step_ROC                matlab.ui.control.NumericEditField
        Window1_max_ROC_speed           matlab.ui.control.NumericEditField
        Window1_min_ROC_speed           matlab.ui.control.NumericEditField
        Window1_step_ROC_speed          matlab.ui.control.NumericEditField
        Window1_max_ROC_drag            matlab.ui.control.NumericEditField
        Window1_min_ROC_drag            matlab.ui.control.NumericEditField
        Window1_step_ROC_drag           matlab.ui.control.NumericEditField
        Step2SelectPropellerTab         matlab.ui.container.Tab
        Window2_display_full_solution_set  matlab.ui.control.Table
        PleasechooseapropellerLabel     matlab.ui.control.Label
        Step3SelectMotorTab             matlab.ui.container.Tab
        Window3_table                   matlab.ui.control.Table
        PleaseChooseaMotorLabel         matlab.ui.control.Label
        Step4FullResultsTab             matlab.ui.container.Tab
        DisplaybestfullresultLabel      matlab.ui.control.Label
        InputParametersPanel            matlab.ui.container.Panel
        CellCount1Label                 matlab.ui.control.Label
        Window4_cell_count              matlab.ui.control.NumericEditField
        CruiseSpeedmsLabel              matlab.ui.control.Label
        Window4_cruise_speed            matlab.ui.control.NumericEditField
        CruiseDragNLabel                matlab.ui.control.Label
        Window4_cruise_thrust           matlab.ui.control.NumericEditField
        MasskgLabel                     matlab.ui.control.Label
        Window4_mass                    matlab.ui.control.NumericEditField
        ROCmsLabel                      matlab.ui.control.Label
        Window4_ROC                     matlab.ui.control.NumericEditField
        ROCVelocitymsLabel              matlab.ui.control.Label
        Window4_ROC_velocity            matlab.ui.control.NumericEditField
        SelectedValuesPanel             matlab.ui.container.Panel
        PropDiaminLabel                 matlab.ui.control.Label
        Window4_Diam                    matlab.ui.control.NumericEditField
        PropPitchinLabel                matlab.ui.control.Label
        Window4_Pitch                   matlab.ui.control.NumericEditField
        MotorKvRPMVLabel                matlab.ui.control.Label
        Window4_motor_Kv                matlab.ui.control.NumericEditField
        MotorMaxPowerWLabel             matlab.ui.control.Label
        Window4_motor_max_power         matlab.ui.control.NumericEditField
        ESCRatingALabel                 matlab.ui.control.Label
        Window4_ESC_rating              matlab.ui.control.NumericEditField
        MotorEstimatedValuesPanel       matlab.ui.container.Panel
        MotorWeightgLabel               matlab.ui.control.Label
        Window4_motor_weight            matlab.ui.control.NumericEditField
        MotorRestistanceOhmsLabel       matlab.ui.control.Label
        Window4_motor_resistance        matlab.ui.control.NumericEditField
        MotorIdleCurrentALabel          matlab.ui.control.Label
        Window4_motor_idle_current      matlab.ui.control.NumericEditField
        CruiseValuesPanel               matlab.ui.container.Panel
        nrevsLabel                      matlab.ui.control.Label
        Window4_n_cruise                matlab.ui.control.NumericEditField
        QNmLabel                        matlab.ui.control.Label
        Window4_Q_cruise                matlab.ui.control.NumericEditField
        ThrustNLabel                    matlab.ui.control.Label
        Window4_thrust_cruise           matlab.ui.control.NumericEditField
        PropellerPowerWLabel            matlab.ui.control.Label
        Window4_power_cruise            matlab.ui.control.NumericEditField
        InputMotorCurrentALabel         matlab.ui.control.Label
        Window4_I_cruise                matlab.ui.control.NumericEditField
        InputMotorVoltageVLabel         matlab.ui.control.Label
        Window4_V_cruise                matlab.ui.control.NumericEditField
        InputMotorPowerWLabel           matlab.ui.control.Label
        Window4_I_V_cruise              matlab.ui.control.NumericEditField
        MotorEfficiency1Label           matlab.ui.control.Label
        Window4_motor_eff_cruise        matlab.ui.control.NumericEditField
        ESCEfficiency1Label             matlab.ui.control.Label
        Window4_ESC_eff_cruise          matlab.ui.control.NumericEditField
        TotalPowerUseWLabel             matlab.ui.control.Label
        Window4_total_power_cruise      matlab.ui.control.NumericEditField
        ROCValuesPanel                  matlab.ui.container.Panel
        nrevsLabel_2                    matlab.ui.control.Label
        Window4_n_ROC                   matlab.ui.control.NumericEditField
        QNmLabel_2                      matlab.ui.control.Label
        Window4_Q_ROC                   matlab.ui.control.NumericEditField
        ThrustNLabel_2                  matlab.ui.control.Label
        Window4_thrust_ROC              matlab.ui.control.NumericEditField
        PropellerPowerWLabel_2          matlab.ui.control.Label
        Window4_power_ROC               matlab.ui.control.NumericEditField
        InputMotorCurrentALabel_2       matlab.ui.control.Label
        Window4_I_ROC                   matlab.ui.control.NumericEditField
        InputMotorVoltageVLabel_2       matlab.ui.control.Label
        Window4_V_ROC                   matlab.ui.control.NumericEditField
        InputMotorPowerWLabel_2         matlab.ui.control.Label
        Window4_I_V_ROC                 matlab.ui.control.NumericEditField
        MotorEfficiency1Label_2         matlab.ui.control.Label
        Window4_motor_eff_ROC           matlab.ui.control.NumericEditField
        ESCEfficiency1Label_2           matlab.ui.control.Label
        Window4_ESC_eff_ROC             matlab.ui.control.NumericEditField
        TotalPowerUseWLabel_2           matlab.ui.control.Label
        Window4_total_power_ROC         matlab.ui.control.NumericEditField
        ComparetochosenvaluesPanel      matlab.ui.container.Panel
        PowerUseofSelectedSystemWLabel  matlab.ui.control.Label
        Window4_total_power_old         matlab.ui.control.NumericEditField
        EfficiencyofSelectedSystem1Label  matlab.ui.control.Label
        Window4_total_eff_old           matlab.ui.control.NumericEditField
        PowerUseofEditedSystemWLabel    matlab.ui.control.Label
        Window4_total_power_new         matlab.ui.control.NumericEditField
        EfficiencyofEditedSystem1Label  matlab.ui.control.Label
        Window4_total_eff_new           matlab.ui.control.NumericEditField
        Window4_check_valid             matlab.ui.control.NumericEditField
        RedoButton                      matlab.ui.control.Button
        Panel3                          matlab.ui.container.Panel
        BatteryCapacityAhLabel          matlab.ui.control.Label
        Window4_capacity                matlab.ui.control.NumericEditField
        AvionicsConstantAveragePowerUseWLabel  matlab.ui.control.Label
        Window4_avionics_power          matlab.ui.control.NumericEditField
        TimespentinClimbminsLabel       matlab.ui.control.Label
        Window4_time_ROC                matlab.ui.control.NumericEditField
        TimespentinCruiseminsLabel      matlab.ui.control.Label
        Window4_time_cruise             matlab.ui.control.NumericEditField
        TotaldistancetravelledkmLabel   matlab.ui.control.Label
        Window4_distance_travelled      matlab.ui.control.NumericEditField
        BatteryCapacityEfficiencyFactorLabel  matlab.ui.control.Label
        Window4_battery_eff             matlab.ui.control.NumericEditField
        PredictMotorRmCheckBox          matlab.ui.control.CheckBox
        PredictMotorIoCheckBox          matlab.ui.control.CheckBox
        CheckMaxpowervsthemotorratedmaximumpowerLabel  matlab.ui.control.Label
        UpdateRangeEstimateLabel        matlab.ui.control.Label
        PredictMotorWeightCheckBox      matlab.ui.control.CheckBox
        Step5GraphsofchangeineffeciencyTab  matlab.ui.container.Tab
        OverridesTab                    matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        Progress_panel                  matlab.ui.container.Panel
        progress_label                  matlab.ui.control.Label
        progress_percent                matlab.ui.control.Label
    end


    methods (Access = private)
    
        function results = clear_tab_4(app)
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: Window1_go_button
        function Window1_go_buttonPushed(app, event)
            
            %max/min diameter and pitch (all in inches)
            
            max_diam=app.Window1_max_Diam.Value;
            min_diam=app.Window1_min_Diam.Value;
            max_pitch=app.Window1_max_Pitch.Value;
            min_pitch=app.Window1_min_Pitch.Value;
            
            max_motor_weight=app.Window1_max_motor_weight.Value;
            max_speed_rotation=app.Window1_max_speed_rotation.Value;
            
            if(max_diam<min_diam)
                disp('The minimum propeller DIAMETER is bigger than the maximum, please reverse the order')
                glitch=questdlg('The minimum propeller DIAMETER is bigger than the maximum, please reverse the order','Error','Whoops','Whoops');
                return;
            end
            if(max_pitch<min_pitch)
                disp('The minimum propeller PITCH is bigger than the maximum, please reverse the order')
                glitch=questdlg('The minimum propeller PITCH is bigger than the maximum, please reverse the order','Error','Whoops','Whoops');
                return;
            end
            
            %Use only purchasable props or use just make up imaginery props
            only_available_props=app.OnlyAvailablePropellersCheckBox.Value;
            if(only_available_props~=0 & only_available_props~=1)
                disp('Please set the value for "only available props" to 0 to search theoretical props and set it to 1 to search only props which are purchaseable')
                return;
            end
            
            %Battery Iteration Bounds (Cell, will assume 3.7V/Cell)
            max_cell_count=app.Window1_max_cell_count.Value;
            min_cell_count=app.Window1_min_cell_count.Value;
            step_cell_count=app.Window1_step_cell_count.Value;
            if(max_cell_count<min_cell_count | step_cell_count<=0)
                disp('The minimum and maximum battery cell count are the wrong way around')
                glitch=questdlg('The minimum and maximum battery cell count are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            
            %cruise speed iteration bounds (m/s)
            max_cruise_speed=app.Window1_max_cruise_speed.Value;
            min_cruise_speed=app.Window1_min_cruise_speed.Value;
            step_cruise_speed=app.Window1_step_cruise_speed.Value;
            if(max_cruise_speed<min_cruise_speed | step_cruise_speed<=0)
                disp('The minimum and maximum cruise speed are the wrong way around')
                glitch=questdlg('The minimum and maximum cruise speed are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            
            %cruise thrust iteration bounds (Newtons)
            max_cruise_thrust=app.Window1_max_cruise_thrust.Value;
            min_cruise_thrust=app.Window1_min_cruise_thrust.Value;
            step_cruise_thrust=app.Window1_step_cruise_thrust.Value;
            if(max_cruise_thrust<min_cruise_thrust | step_cruise_thrust<=0)
                disp('The minimum and maximum cruise thrust are the wrong way around')
                glitch=questdlg('The minimum and maximum cruise thrust are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            
            %mass iteration bounds (kg)
            max_mass=app.Window1_max_mass.Value;
            min_mass=app.Window1_min_mass.Value;
            step_mass=app.Window1_step_mass.Value;
            if(max_mass<min_mass | step_mass<=0)
                disp('The minimum and maximum mass are the wrong way around')
                glitch=questdlg('The minimum and maximum mass are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            
            %ROC rates iteration bounds (m/s)
            max_ROC=app.Window1_max_ROC.Value;
            min_ROC=app.Window1_min_ROC.Value;
            step_ROC=app.Window1_step_ROC.Value;
            if(max_ROC<min_ROC | step_ROC<=0)
                disp('The minimum and maximum ROC are the wrong way around')
                glitch=questdlg('The minimum and maximum ROC are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            
            %ROC speed iteration bounds; This is the speed at which the aircraft is
            %travelling under the maximum rate of climb
            %if set to -1
            max_ROC_speed=app.Window1_max_ROC_speed.Value;
            min_ROC_speed=app.Window1_min_ROC_speed.Value;
            step_ROC_speed=app.Window1_step_ROC_speed.Value;
            if(max_ROC_speed<min_ROC_speed | step_ROC_speed==0)
                disp('The minimum and maximum ROC forward velocity are the wrong way around')
                glitch=questdlg('The minimum and maximum ROC forward velocity are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            if(step_ROC<=0 & step_ROC~=-1)
                disp('There was an issule with the ROC values, check the negatives')
                glitch=questdlg('There was an issule with the ROC values, check the negatives','Error','Whoops','Whoops');
                return;
            end
            
            
            %ROC Drag to account for the fact that flaps may be used at the maximum ROC
            %if set to -1 then aircraft will be assumed to be in the same configuration
            %as in cruise
            max_ROC_drag=app.Window1_max_ROC_drag.Value;
            min_ROC_drag=app.Window1_min_ROC_drag.Value;
            step_ROC_drag=app.Window1_step_ROC_drag.Value;
            if(max_ROC_drag<min_ROC_drag | step_ROC_drag==0)
                disp('The minimum and maximum ROC drag are the wrong way around')
                glitch=questdlg('The minimum and maximum ROC drag are the wrong way around','Error','Whoops','Whoops');
                return;
            end
            if(step_ROC_drag<=0 & step_ROC_drag~=-1)
                disp('There was an issue with the ROC Velocity values, check the negatives')
                glitch=questdlg('There was an issue with the ROC Velocity values, check the negatives','Error','Whoops','Whoops');
                return;
            end
            
            local_directory=pwd;
            %Load the coefficients for graphs
            filename=strcat(local_directory,'\Ct_coefficients.xlsx');
            Ct_load=xlsread(filename);
            filename=strcat(local_directory,'\Cp_coefficients.xlsx');
            Cp_load=xlsread(filename);
            filename=strcat(local_directory,'\eta_coefficients.xlsx');
            eta_load=xlsread(filename);


            if(only_available_props==1)
    filename=strcat(local_directory,'\purchasable_propellers.xlsx');
    avail_props=xlsread(filename);
    avail_props_diam=avail_props(:,1);
    avail_props_pitch=avail_props(:,2);
            
    logic=avail_props_diam<=max_diam;
    avail_props_diam=avail_props_diam(logic);
    avail_props_pitch=avail_props_pitch(logic);
            
    logic=avail_props_diam>=min_diam;
    avail_props_diam=avail_props_diam(logic);
    avail_props_pitch=avail_props_pitch(logic);
            
    logic=avail_props_pitch<=max_pitch;
    avail_props_diam=avail_props_diam(logic);
    avail_props_pitch=avail_props_pitch(logic);
            
    logic=avail_props_pitch>=min_pitch;
    avail_props_diam=avail_props_diam(logic);
    avail_props_pitch=avail_props_pitch(logic);
            else
    m=0;
    for i=min_diam:1:max_diam
        for j=min_pitch:1:max_pitch;
            m=m+1;
            avail_props_diam(m)=i;
            avail_props_pitch(m)=j;
        end
    end
    avail_props_diam=avail_props_diam';
    avail_props_pitch=avail_props_pitch';
            end
            
            %This will be the whole overall storage which gets presented to the user
            store_total=zeros(8000000,41);
            
            %Insert a Check such that too many iterations can't be run which would crash the computer
            iteration_total=ceil((app.Window1_max_cell_count.Value-app.Window1_min_cell_count.Value)/app.Window1_step_cell_count.Value)+1;
            iteration_total=iteration_total*(ceil((app.Window1_max_cruise_speed.Value-app.Window1_min_cruise_speed.Value)/app.Window1_step_cruise_speed.Value)+1);
            iteration_total=iteration_total*(ceil((app.Window1_max_cruise_thrust.Value-app.Window1_min_cruise_thrust.Value)/app.Window1_step_cruise_thrust.Value)+1);
            iteration_total=iteration_total*(ceil((app.Window1_max_mass.Value-app.Window1_min_mass.Value)/app.Window1_step_mass.Value)+1);
            iteration_total=iteration_total*(ceil((app.Window1_max_ROC.Value-app.Window1_min_ROC.Value)/app.Window1_step_ROC.Value)+1);
            iteration_total=iteration_total*(ceil((app.Window1_max_ROC_speed.Value-app.Window1_min_ROC_speed.Value)/app.Window1_step_ROC_speed.Value)+1);
            iteration_total=iteration_total*(ceil((app.Window1_max_ROC_drag.Value-app.Window1_min_ROC_drag.Value)/app.Window1_step_ROC_drag.Value)+1);
            
            iteration_max=10;
            if(iteration_total>iteration_max)
                text=strcat('You have selected to use_',num2str(iteration_total),' iterations based on the variation in your input values.  This is above the recommended_',num2str(iteration_max),' and continuing may be quite slow, do you wish to continue?');
                
                choice=questdlg(text,'WARNING!','Yes','No','No');
                
                if(choice=='Yes')
                    
                elseif(choice=='No')
                    return;
                end
            end


            iteration_count=1;
            
            total_rows=1;
            %This is the main body of the program which runs through the prop diam and
            %pitch iteration for each of the below values
            for cell_count=min_cell_count:step_cell_count:max_cell_count
    hell_world=1
    for cruise_speed=min_cruise_speed:step_cruise_speed:max_cruise_speed
        hello_world=2
        for cruise_thrust=min_cruise_thrust:step_cruise_thrust:max_cruise_thrust
            hellow_world=3
            for mass=min_mass:step_mass:max_mass
                for ROC=min_ROC:step_ROC:max_ROC
                    for ROC_speed=min_ROC_speed:step_ROC_speed:max_ROC_speed
                        for ROC_drag=min_ROC_drag:step_ROC_drag:max_ROC_drag
                    
                            %most of this is for matrix pre-allocation when
                            %there are a few million results
                            [store_local]=plane_management_function(Ct_load,Cp_load,eta_load,avail_props_diam,avail_props_pitch,cell_count,cruise_speed,cruise_thrust,mass,ROC,ROC_speed,ROC_drag,max_motor_weight,max_speed_rotation);
                            [a,b]=size(store_local);
                            store_total([total_rows:total_rows+a-1],[1:b])=store_local;
                            total_rows=total_rows+a;
                            app.progress_percent.Text=strcat(num2str(iteration_count/(iteration_total+1)*100,3),'%');
                            iteration_count=iteration_count+1;
                            
                        end
                    end
                end
            end
        end
    end
            end
            hello_world=1
            total_rows
            if(total_rows==1)
                glitch=questdlg('No Solutions were found, please relax restrictions','Error','Whoops','Whoops');
                return;
            end
            logical=store_total(:,end)>0;
            store_total=store_total(logical,:);


            file_header={'cell count','cruise speed','cruise thrust','mass','ROC','ROC_velocity','Kv','Max Motor Power','Motor Weight','Rm','Io','Motor efficiency ROC','Motor efficiency cruise','Diam','Pitch','n_ROC','Q_ROC','Thrust_ROC','Power_ROC','I_ROC','V_ROC','I*V_ROC','Total Power ROC','n_max','torque max','thrust max','power max','velocity max','I max','V max','I*V_max','ESC_eff_ROC','ESC_eff_cruise','n_cruise','Q_cruise','Thrust_cruise','Power_cruise','I_cruise','V_cruise','I*V_cruise','Total Power cruise'};
            
            %             filename=strcat(local_directory,'\output_given_motor_find_propeller18.csv');
            %              fid = fopen(filename, 'w') ;
            %              fprintf(fid, '%s,', file_header{1,1:end-1}) ;
            %              fprintf(fid, '%s\n', file_header{1,end}) ;
            %              fclose(fid) ;
            %             
            %              dlmwrite(filename, store_total, '-append') ;
            %              he_world=1
            %             if(total_rows<300000)
            %                 store_total_cell=num2cell(store_total);
            %                 store_total_cell=vertcat(file_header,store_total_cell);
            %                 filename=strcat(local_directory,'\output_given_motor_find_propeller18.xlsx');
            %                 xlswrite(filename,store_total_cell);
            %     
            %             end
            
            
            %This whole section is to make the second tab just list the best motor and esc for A GIVEN PROP to allow the used to choose the best propeller
            %The user will then be prompted to choose the best motor afterwards
            store_min_power=zeros(1000000,b+1);
            m=1;
            random_old=store_total(1,1)*store_total(1,2)*store_total(1,3)*store_total(1,4)*store_total(1,5)*store_total(1,6)*store_total(1,14)*store_total(1,15);
            lowest_power=999999;
            best_i=0;
            for i=1:total_rows-1
                random_current=store_total(i,1)*store_total(i,2)*store_total(i,3)*store_total(i,4)*store_total(i,5)*store_total(i,6)*store_total(i,14)*store_total(i,15);
                if(random_current==random_old)
                    %still in the same flight conditions with the same propeller
                    if(store_total(i,b)<lowest_power)
                        best_i=i;
                        lowest_power=store_total(i,b);
                    end
                else
                    %in new flight condition and need to save the best value from the
                    %old one
                    store_min_power(m,:)=[store_total(best_i,:),best_i];
                    m=m+1;
                    lowest_power=store_total(i,b);
                    best_i=i;
                    random_old=random_current;
                end
                
            end
            
            logical=store_min_power(:,1)>0;
            store_min_power=store_min_power(logical,:);
            
            condensed_min_power=store_min_power(:,[b,14,15,9,1,2,3,4,5,6,7,8,end]);
            condensed_min_power=sortrows(condensed_min_power,1);
            
            set(app.Window3_table,'Data',[]);
            clear=[];
            filename='prop_selected.mat';
            save(filename,'clear');
            clear_tab_4(app);
            filename='motor_selected.mat';
            save(filename,'clear');
            set(app.Window2_display_full_solution_set,'Data',condensed_min_power); % Use the set command to change the uitable properties.
            set(app.Window2_display_full_solution_set,'ColumnName',{'Cruise Power','Propeller Diam','Propeller Pitch','Motor Weight','Cell Count','Cruise speed','Cruise Thrust','Mass','ROC','ROC Velocity','Kv','Motor Max Power','i'})
            
            
            app.progress_percent.Text='100%';
            
            app.Window4_check_valid.Value=0;
            app.TabGroup.SelectedTab=app.Step2SelectPropellerTab;%'Step 2 - Select Propeller';
            
            
            filename='condensed_min_power.mat';
            save(filename,'condensed_min_power');
            filename='store_total.mat';
            save(filename,'store_total');
          
            
        end

        % Cell selection callback: Window2_display_full_solution_set
        function Window2_display_full_solution_setCellSelection(app, event)
            %A propeller and flight condition have now been selected and I would like to display just this propeller with all the available motors
            
            %local_directory=pwd;
            filename='condensed_min_power.mat';
            condensed_min_power=load(filename,'condensed_min_power');
            condensed_min_power=condensed_min_power.condensed_min_power;
            
            filename='store_total.mat';
            store_total=load(filename,'store_total');
            store_total=store_total.store_total;
            
            prop_row=event.Indices(1);
            prop_column=event.Indices(2);
            full_lookup_row=condensed_min_power(prop_row,end);
            chosen_diam=store_total(full_lookup_row,14);
            chosen_pitch=store_total(full_lookup_row,15);
            %unsure whether I should give the prop in all the specified conditions
            
            %This is the find just the propeller in the given flight conditions and to show all the possible motor configurations
            random_chosen=store_total(full_lookup_row,1)*store_total(full_lookup_row,2)*store_total(full_lookup_row,3)*store_total(full_lookup_row,4)*store_total(full_lookup_row,5)*store_total(full_lookup_row,6)*store_total(full_lookup_row,14)*store_total(full_lookup_row,15);
            random_current=random_chosen;
            count_back=0;
            while(random_current==random_chosen)
                current_row=full_lookup_row-count_back;
                if(current_row<=0)
                    break;
                end
                random_current=store_total(current_row,1)*store_total(current_row,2)*store_total(current_row,3)*store_total(current_row,4)*store_total(current_row,5)*store_total(current_row,6)*store_total(current_row,14)*store_total(current_row,15);
                count_back=count_back+1;
            end
            count_back=count_back-2;
            
            random_chosen=store_total(full_lookup_row,1)*store_total(full_lookup_row,2)*store_total(full_lookup_row,3)*store_total(full_lookup_row,4)*store_total(full_lookup_row,5)*store_total(full_lookup_row,6)*store_total(full_lookup_row,14)*store_total(full_lookup_row,15);
            random_current=random_chosen;
            count_forward=0;
            [a,b]=size(store_total);
            while(random_current==random_chosen)
                current_row=full_lookup_row+count_forward;
                if(current_row>a)
                    break;
                end
                random_current=store_total(current_row,1)*store_total(current_row,2)*store_total(current_row,3)*store_total(current_row,4)*store_total(current_row,5)*store_total(current_row,6)*store_total(current_row,14)*store_total(current_row,15);
                count_forward=count_forward+1;
            end
            count_forward=count_forward-2;
            
            
            prop_selected=store_total([full_lookup_row-count_back:full_lookup_row+count_forward],:);
            [a,b]=size(prop_selected);
            prop_selected=sortrows(prop_selected,b);
            
            clear_tab_4(app);
            clear=[];
            filename='motor_selected.mat';
            save(filename,'clear');
            set(app.Window3_table,'ColumnName',{'cruise power','Kv','Max Motor Power','Power need in ROC','Motor weight','Motor Efficiency cruise','Motor Efficiency ROC','Diam','Pitch','Cell Count','cruise speed','cruise thrust','mass','ROC','ROC_velocity'});
            set(app.Window3_table,'Data',prop_selected(:,[end,7,8,23,9,13,12,14,15,1,2,3,4,5,6]));
            
            filename='prop_selected.mat';
            save(filename,'prop_selected');
            
            app.Window4_check_valid.Value=0;
            app.TabGroup.SelectedTab=app.Step3SelectMotorTab;


        end

        % Cell selection callback: Window3_table
        function Window3_tableCellSelection(app, event)
            filename='condensed_min_power.mat';
            condensed_min_power=load(filename,'condensed_min_power');
            condensed_min_power=condensed_min_power.condensed_min_power;
            
            filename='store_total.mat';
            store_total=load(filename,'store_total');
            store_total=store_total.store_total;
            
            filename='prop_selected.mat';
            prop_selected=load(filename,'prop_selected');
            prop_selected=prop_selected.prop_selected;
            
            motor_row=event.Indices(1);
            motor_column=event.Indices(2);
            
            motor_selected=prop_selected(motor_row,:);
            
            filename='motor_selected.mat';
            save(filename,'motor_selected');
            
            motor_edited=motor_selected;
            filename='motor_edited.mat';
            save(filename,'motor_edited');
            
            %set(app.Window4_table,'ColumnName',{'cell count','cruise speed','cruise thrust','mass','ROC','ROC_velocity','Kv','Max Motor Power','Motor Weight','Rm','Io','Motor efficiency ROC','Motor efficiency cruise','Diam','Pitch','n_ROC','Q_ROC','Thrust_ROC','Power_ROC','I_ROC','V_ROC','I*V_ROC','Total Power ROC','n_max','torque max','thrust max','power max','velocity max','I max','V max','I*V_max','ESC_eff_ROC','ESC_eff_cruise','n_cruise','Q_cruise','Thrust_cruise','Power_cruise','I_cruise','V_cruise','I*V_cruise','Total Power cruise'});
            %set(app.Window4_table,'Data',motor_selected);
            app.Window4_check_valid.Value=1;
            
            %entering all of the relevant values into the fourth tab, this will take a while
            app.Window4_cell_count.Value=motor_selected(1);
            app.Window4_cruise_speed.Value=motor_selected(2);
            app.Window4_cruise_thrust.Value=motor_selected(3);
            app.Window4_mass.Value=motor_selected(4);
            app.Window4_ROC.Value=motor_selected(5);
            app.Window4_ROC_velocity.Value=motor_selected(6);
            app.Window4_motor_Kv.Value=motor_selected(7);
            app.Window4_motor_max_power.Value=motor_selected(8);
            app.Window4_motor_weight.Value=motor_selected(9);
            app.Window4_motor_resistance.Value=motor_selected(10);
            app.Window4_motor_idle_current.Value=motor_selected(11);
            app.Window4_ESC_rating.Value=ceil(motor_selected(20)*1.2/5)*5;
            app.Window4_motor_eff_ROC.Value=motor_selected(12);
            app.Window4_motor_eff_cruise.Value=motor_selected(13);
            app.Window4_Diam.Value=motor_selected(14);
            app.Window4_Pitch.Value=motor_selected(15);
            app.Window4_n_ROC.Value=motor_selected(16);
            app.Window4_Q_ROC.Value=motor_selected(17);
            app.Window4_thrust_ROC.Value=motor_selected(18);
            app.Window4_power_ROC.Value=motor_selected(19);
            app.Window4_I_ROC.Value=motor_selected(20);
            app.Window4_V_ROC.Value=motor_selected(21);
            app.Window4_I_V_ROC.Value=motor_selected(22);
            app.Window4_total_power_ROC.Value=motor_selected(23);
            app.Window4_ESC_eff_ROC.Value=motor_selected(32);
            app.Window4_ESC_eff_cruise.Value=motor_selected(33);
            app.Window4_n_cruise.Value=motor_selected(34);
            app.Window4_Q_cruise.Value=motor_selected(35);
            app.Window4_thrust_cruise.Value=motor_selected(36);
            app.Window4_power_cruise.Value=motor_selected(37);
            app.Window4_I_cruise.Value=motor_selected(38);
            app.Window4_V_cruise.Value=motor_selected(39);
            app.Window4_I_V_cruise.Value=motor_selected(40);
            app.Window4_total_power_cruise.Value=motor_selected(41);
            
            app.Window4_total_power_old.Value=motor_selected(41);
            app.Window4_total_power_new.Value=motor_selected(41);
            app.Window4_total_eff_old.Value=motor_selected(36)*motor_selected(2)/motor_selected(41);
            app.Window4_total_eff_new.Value=motor_selected(36)*motor_selected(2)/motor_selected(41);


            app.TabGroup.SelectedTab=app.Step4FullResultsTab;


        end

        % Value changed function: Window4_Diam
        function Window4_DiamValueChanged(app, event)
            value = app.Window4_Diam.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_Diam.Value=motor_selected(14);
            end
        end

        % Value changed function: Window4_ESC_rating
        function Window4_ESC_ratingValueChanged(app, event)
            value = app.Window4_ESC_rating.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_ESC_rating.Value=ceil(motor_selected(20)*1.2/5)*5;
            end
        end

        % Value changed function: Window4_Pitch
        function Window4_PitchValueChanged(app, event)
            value = app.Window4_Pitch.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_Pitch.Value=motor_selected(15);
            end
        end

        % Value changed function: Window4_ROC
        function Window4_ROCValueChanged(app, event)
            value = app.Window4_ROC.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_ROC.Value=motor_selected(5);
            end
        end

        % Value changed function: Window4_ROC_velocity
        function Window4_ROC_velocityValueChanged(app, event)
            value = app.Window4_ROC_velocity.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_ROC_velocity.Value=motor_selected(6);
            end
        end

        % Value changed function: Window4_avionics_power
        function Window4_avionics_powerValueChanged(app, event)
            value = app.Window4_avionics_power.Value;
            
            
            filename='motor_edited.mat';
            motor_edited=load(filename);
            motor_edited=motor_edited.motor_edited;
            
            
            cruise_time=(app.Window4_capacity.Value*motor_edited(1)*3.7*app.Window4_battery_eff.Value)/(app.Window4_avionics_power.Value+app.Window4_total_power_cruise.Value)*60;
            app.Window4_time_cruise.Value=cruise_time;
            
            distance=cruise_time*motor_edited(2)*3.6/60;
            app.Window4_distance_travelled.Value=distance;
        end

        % Value changed function: Window4_capacity
        function Window4_capacityValueChanged(app, event)
            value = app.Window4_capacity.Value;
            
            filename='motor_edited.mat';
            motor_edited=load(filename);
            motor_edited=motor_edited.motor_edited;
            
            
            cruise_time=(app.Window4_capacity.Value*motor_edited(1)*3.7*app.Window4_battery_eff.Value)/(app.Window4_avionics_power.Value+app.Window4_total_power_cruise.Value)*60;
            app.Window4_time_cruise.Value=cruise_time;
            
            distance=cruise_time*motor_edited(2)*3.6/60;
            app.Window4_distance_travelled.Value=distance;
            
        end

        % Value changed function: Window4_cell_count
        function Window4_cell_countValueChanged(app, event)
            value = app.Window4_cell_count.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_cell_count.Value=motor_selected(1);
            end
        end

        % Value changed function: Window4_cruise_speed
        function Window4_cruise_speedValueChanged(app, event)
            value = app.Window4_cruise_speed.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_cruise_speed.Value=motor_selected(2);
            end
        end

        % Value changed function: Window4_cruise_thrust
        function Window4_cruise_thrustValueChanged(app, event)
            value = app.Window4_cruise_thrust.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_cruise_thrust.Value=motor_selected(3);
            end
        end

        % Value changed function: Window4_mass
        function Window4_massValueChanged(app, event)
            value = app.Window4_mass.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_mass.Value=motor_selected(4);
            end
        end

        % Value changed function: Window4_motor_Kv
        function Window4_motor_KvValueChanged(app, event)
            value = app.Window4_motor_Kv.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_motor_Kv.Value=motor_selected(7);
            end
        end

        % Value changed function: Window4_motor_idle_current
        function Window4_motor_idle_currentValueChanged(app, event)
            value = app.Window4_motor_idle_current.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_motor_idle_current.Value=motor_selected(11);
                
            end
        end

        % Value changed function: Window4_motor_max_power
        function Window4_motor_max_powerValueChanged(app, event)
            value = app.Window4_motor_max_power.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_motor_max_power.Value=motor_selected(8);
            end
        end

        % Value changed function: Window4_motor_resistance
        function Window4_motor_resistanceValueChanged(app, event)
            value = app.Window4_motor_resistance.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_motor_resistance.Value=motor_selected(10);
                
            end
        end

        % Value changed function: Window4_motor_weight
        function Window4_motor_weightValueChanged(app, event)
            value = app.Window4_motor_weight.Value;
            
            if(value==-1 & app.Window4_check_valid.Value==1)
                filename='motor_selected.mat';
                motor_selected=load(filename,'motor_selected');
                motor_selected=motor_selected.motor_selected;
                app.Window4_motor_weight.Value=motor_selected(9);
            end
        end

        % Value changed function: Window4_time_ROC
        function Window4_time_ROCValueChanged(app, event)
            value = app.Window4_time_ROC.Value;
            
            filename='motor_edited.mat';
            motor_edited=load(filename);
            motor_edited=motor_edited.motor_edited;
            
            
            cruise_time=(app.Window4_capacity.Value*motor_edited(1)*3.7*app.Window4_battery_eff.Value)/(app.Window4_avionics_power.Value+app.Window4_total_power_cruise.Value)*60;
            app.Window4_time_cruise.Value=cruise_time;
            
            distance=cruise_time*motor_edited(2)*3.6/60;
            app.Window4_distance_travelled.Value=distance;
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            
            local_directory=pwd;
            %Load the coefficients for graphs
            filename=strcat(local_directory,'\Ct_coefficients.xlsx');
            Ct_load=xlsread(filename);
            filename=strcat(local_directory,'\Cp_coefficients.xlsx');
            Cp_load=xlsread(filename);
            filename=strcat(local_directory,'\eta_coefficients.xlsx');
            eta_load=xlsread(filename);
            
            %need to force the value to be sane!
            
            Diam=app.Window4_Diam.Value;
            
            Pitch=app.Window4_Pitch.Value;
            cell_count=app.Window4_cell_count.Value;
            cruise_speed=app.Window4_cruise_speed.Value;
            cruise_thrust=app.Window4_cruise_thrust.Value;
            mass=app.Window4_mass.Value;
            ROC=app.Window4_ROC.Value;
            ROC_speed=app.Window4_ROC_velocity.Value;
            ROC_drag=app.Window4_cruise_thrust.Value*app.Window4_ROC_velocity.Value^2/app.Window4_cruise_speed.Value^2;
            predict_Rm=app.PredictMotorRmCheckBox.Value;
            predict_Io=app.PredictMotorIoCheckBox.Value;
            predict_motor_weight=app.PredictMotorWeightCheckBox.Value;
            Rm_input=app.Window4_motor_resistance.Value;
            Io_input=app.Window4_motor_idle_current.Value;
            motor_weight_input=app.Window4_motor_weight.Value;
            ESC_rating=app.Window4_ESC_rating.Value;
            motor_max_power=app.Window4_motor_max_power.Value;
            Kv=app.Window4_motor_Kv.Value;
            max_speed_rotation=app.Window1_max_speed_rotation.Value;
            max_motor_weight=app.Window1_max_motor_weight.Value;
            
            
            [motor_edited,valid_return]=plane_management_function_rerun(Ct_load,Cp_load,eta_load,Diam,Pitch,cell_count,cruise_speed,cruise_thrust,mass,ROC,ROC_speed,ROC_drag,predict_Rm,predict_Io,predict_motor_weight,Kv,motor_max_power,Rm_input,Io_input,motor_weight_input,ESC_rating,max_speed_rotation,max_motor_weight)
            
            if(valid_return==-1)
                glitch=questdlg('No solution found for the Maximum ROC case for the given inputs','Error','Whoops','Whoops');
            end
            
            if(valid_return==-2)
                glitch=questdlg('No solution found for the cruise case for the given inputs','Error','Whoops','Whoops');
            end
            
            if(valid_return==-3)
                glitch=questdlg('The ROC case uses more than 85% throttle and is not recommended or impossible, check V_ROC value vs battery voltage','Error','Whoops','Whoops');
            end
            
            if(valid_return==-4)
                glitch=questdlg('Maximum Motor Weight Exceeded','Error','Whoops','Whoops');
            end
                        
            if(valid_return==-5)
                glitch=questdlg('Power for ROC case exceeds the maximum motor rating','Error','Whoops','Whoops');
            end
            
            %entering all of the relevant values into the fourth tab, this will take a while
            app.Window4_cell_count.Value=motor_edited(1);
            app.Window4_cruise_speed.Value=motor_edited(2);
            app.Window4_cruise_thrust.Value=motor_edited(3);
            app.Window4_mass.Value=motor_edited(4);
            app.Window4_ROC.Value=motor_edited(5);
            app.Window4_ROC_velocity.Value=motor_edited(6);
            app.Window4_motor_Kv.Value=motor_edited(7);
            app.Window4_motor_max_power.Value=motor_edited(8);
            app.Window4_motor_weight.Value=motor_edited(9);
            app.Window4_motor_resistance.Value=motor_edited(10);
            app.Window4_motor_idle_current.Value=motor_edited(11);
            
            %app.Window4_ESC_rating.Value=ceil(motor_edited(20)*1.2/5)*5;
            app.Window4_motor_eff_ROC.Value=motor_edited(12);
            app.Window4_motor_eff_cruise.Value=motor_edited(13);
            app.Window4_Diam.Value=motor_edited(14);
            app.Window4_Pitch.Value=motor_edited(15);
            app.Window4_n_ROC.Value=motor_edited(16);
            app.Window4_Q_ROC.Value=motor_edited(17);
            app.Window4_thrust_ROC.Value=motor_edited(18);
            app.Window4_power_ROC.Value=motor_edited(19);
            app.Window4_I_ROC.Value=motor_edited(20);
            app.Window4_V_ROC.Value=motor_edited(21);
            app.Window4_I_V_ROC.Value=motor_edited(22);
            app.Window4_total_power_ROC.Value=motor_edited(23);
            app.Window4_ESC_eff_ROC.Value=motor_edited(32);
            app.Window4_ESC_eff_cruise.Value=motor_edited(33);
            app.Window4_n_cruise.Value=motor_edited(34);
            app.Window4_Q_cruise.Value=motor_edited(35);
            app.Window4_thrust_cruise.Value=motor_edited(36);
            app.Window4_power_cruise.Value=motor_edited(37);
            app.Window4_I_cruise.Value=motor_edited(38);
            app.Window4_V_cruise.Value=motor_edited(39);
            app.Window4_I_V_cruise.Value=motor_edited(40);
            app.Window4_total_power_cruise.Value=motor_edited(41);
            

            app.Window4_total_power_new.Value=motor_edited(41);

            app.Window4_total_eff_new.Value=motor_edited(36)*motor_edited(2)/motor_edited(41);


            filename='motor_edited.mat';
            save(filename,'motor_edited');


            cruise_time=(app.Window4_capacity.Value*motor_edited(1)*3.7*app.Window4_battery_eff.Value)/(app.Window4_avionics_power.Value+app.Window4_total_power_cruise.Value)*60;
            app.Window4_time_cruise.Value=cruise_time;
            
            distance=cruise_time*motor_edited(2)*3.6/60;
            app.Window4_distance_travelled.Value=distance;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Motor_Prop_Calc = uifigure;
            app.Motor_Prop_Calc.Name = 'Motor Prop Calc';
            app.Window1_header_name = uilabel(app.Motor_Prop_Calc);
            app.Window1_header_name.Text = 'Fixed-Wing Propulsion System';
            app.TabGroup = uitabgroup(app.Motor_Prop_Calc);
            app.Step1InputsTab = uitab(app.TabGroup);
            app.Step1InputsTab.Title = 'Step 1 - Inputs';
            app.InputPlaneConditionsLabel = uilabel(app.Step1InputsTab);
            app.InputPlaneConditionsLabel.Text = 'Input Plane Conditions';
            app.Panel = uipanel(app.Step1InputsTab);
            app.Panel.Title = 'Panel';
            app.InputLimitingConditionsLabel = uilabel(app.Panel);
            app.InputLimitingConditionsLabel.Text = 'Input Limiting Conditions';
            app.MaxDiameterLabel = uilabel(app.Panel);
            app.MaxDiameterLabel.Text = 'Max Diameter';
            app.MinimumDiameterLabel = uilabel(app.Panel);
            app.MinimumDiameterLabel.Text = 'Minimum Diameter';
            app.MaximumPitchLabel = uilabel(app.Panel);
            app.MaximumPitchLabel.Text = 'Maximum Pitch';
            app.MinimumPitchLabel = uilabel(app.Panel);
            app.MinimumPitchLabel.Text = 'Minimum Pitch';
            app.Window1_max_Diam = uieditfield(app.Panel, 'numeric');
            app.Window1_max_Diam.Limits = [0 999];
            app.Window1_max_Diam.Value = 99;
            app.Window1_min_Diam = uieditfield(app.Panel, 'numeric');
            app.Window1_min_Diam.Limits = [0 999];
            app.Window1_max_Pitch = uieditfield(app.Panel, 'numeric');
            app.Window1_max_Pitch.Limits = [0 999];
            app.Window1_max_Pitch.Value = 99;
            app.Window1_min_Pitch = uieditfield(app.Panel, 'numeric');
            app.Window1_min_Pitch.Limits = [0 99];
            app.MaximumBatteryCellsLabel = uilabel(app.Panel);
            app.MaximumBatteryCellsLabel.Text = 'Maximum Battery Cells';
            app.MinimumBatteryCellsLabel = uilabel(app.Panel);
            app.MinimumBatteryCellsLabel.Text = 'Minimum Battery Cells';
            app.Window1_max_cell_count = uieditfield(app.Panel, 'numeric');
            app.Window1_max_cell_count.Limits = [0 20];
            app.Window1_max_cell_count.ValueDisplayFormat = '%.0f';
            app.Window1_max_cell_count.Value = 8;
            app.Window1_min_cell_count = uieditfield(app.Panel, 'numeric');
            app.Window1_min_cell_count.Limits = [0 20];
            app.Window1_min_cell_count.ValueDisplayFormat = '%.0f';
            app.Window1_min_cell_count.Value = 6;
            app.OnlyAvailablePropellersCheckBox = uicheckbox(app.Panel);
            app.OnlyAvailablePropellersCheckBox.Text = 'Only Available Propellers';
            app.OnlyAvailablePropellersCheckBox.Value = true;
            app.StepBatteryCellsLabel = uilabel(app.Panel);
            app.StepBatteryCellsLabel.Text = 'Step Battery Cells';
            app.Window1_step_cell_count = uieditfield(app.Panel, 'numeric');
            app.Window1_step_cell_count.Limits = [0 12];
            app.Window1_step_cell_count.ValueDisplayFormat = '%.0f';
            app.Window1_step_cell_count.Value = 2;
            app.MaxMotorWeightLabel = uilabel(app.Panel);
            app.MaxMotorWeightLabel.Text = 'Max Motor Weight';
            app.Window1_max_motor_weight = uieditfield(app.Panel, 'numeric');
            app.Window1_max_motor_weight.Limits = [0 9999];
            app.Window1_max_motor_weight.ValueDisplayFormat = '%.0f';
            app.Window1_max_motor_weight.Value = 9999;
            app.MaxRotorSpeedLabel = uilabel(app.Panel);
            app.MaxRotorSpeedLabel.Text = 'Max Rotor Speed';
            app.Window1_max_speed_rotation = uieditfield(app.Panel, 'numeric');
            app.Window1_max_speed_rotation.Limits = [0 99999];
            app.Window1_max_speed_rotation.ValueDisplayFormat = '%.0f';
            app.Window1_max_speed_rotation.Value = 9000;
            app.Window1_go_button = uibutton(app.Step1InputsTab, 'push');
            app.Window1_go_button.ButtonPushedFcn = createCallbackFcn(app, @Window1_go_buttonPushed, true);
            app.Window1_go_button.Text = 'Go';
            app.Panel2 = uipanel(app.Step1InputsTab);
            app.Panel2.Title = 'Panel2';
            app.KnownPlaneConditionsLabel = uilabel(app.Panel2);
            app.KnownPlaneConditionsLabel.Text = 'Known Plane Conditions';
            app.CruiseSpeed_maxLabel = uilabel(app.Panel2);
            app.CruiseSpeed_maxLabel.Text = 'Cruise Speed_max';
            app.CruiseSpeed_minLabel = uilabel(app.Panel2);
            app.CruiseSpeed_minLabel.Text = 'Cruise Speed_min';
            app.CruiseSpeed_stepLabel = uilabel(app.Panel2);
            app.CruiseSpeed_stepLabel.Text = 'Cruise Speed_step';
            app.CruiseDrag_maxLabel = uilabel(app.Panel2);
            app.CruiseDrag_maxLabel.Text = 'Cruise Drag_max';
            app.CruiseDrag_minLabel = uilabel(app.Panel2);
            app.CruiseDrag_minLabel.Text = 'Cruise Drag_min';
            app.CruiseDrag_stepLabel = uilabel(app.Panel2);
            app.CruiseDrag_stepLabel.Text = 'Cruise Drag_step';
            app.Mass_maxLabel = uilabel(app.Panel2);
            app.Mass_maxLabel.Text = 'Mass_max';
            app.Mass_minLabel = uilabel(app.Panel2);
            app.Mass_minLabel.Text = 'Mass_min';
            app.Mass_stepLabel = uilabel(app.Panel2);
            app.Mass_stepLabel.Text = 'Mass_step';
            app.ROC_maxLabel = uilabel(app.Panel2);
            app.ROC_maxLabel.Text = 'ROC_max';
            app.ROC_minLabel = uilabel(app.Panel2);
            app.ROC_minLabel.Text = 'ROC_min';
            app.ROC_stepLabel = uilabel(app.Panel2);
            app.ROC_stepLabel.Text = 'ROC_step';
            app.VelocityduringROC_maxLabel = uilabel(app.Panel2);
            app.VelocityduringROC_maxLabel.Text = 'Velocity during ROC_max';
            app.VelocityduringROC_minLabel = uilabel(app.Panel2);
            app.VelocityduringROC_minLabel.Text = 'Velocity during ROC_min';
            app.VelocityduringROC_stepLabel = uilabel(app.Panel2);
            app.VelocityduringROC_stepLabel.Text = 'Velocity during ROC_step';
            app.DragduringROC_maxLabel = uilabel(app.Panel2);
            app.DragduringROC_maxLabel.Text = 'Drag during ROC_max';
            app.DragduringROC_minLabel = uilabel(app.Panel2);
            app.DragduringROC_minLabel.Text = 'Drag during ROC_min';
            app.DragduringROC_stepLabel = uilabel(app.Panel2);
            app.DragduringROC_stepLabel.Text = 'Drag during ROC_step';
            app.Window1_max_cruise_speed = uieditfield(app.Panel2, 'numeric');
            app.Window1_max_cruise_speed.Limits = [0 50];
            app.Window1_max_cruise_speed.Value = 27;
            app.Window1_min_cruise_speed = uieditfield(app.Panel2, 'numeric');
            app.Window1_min_cruise_speed.Limits = [0 50];
            app.Window1_min_cruise_speed.Value = 24;
            app.Window1_step_cruise_speed = uieditfield(app.Panel2, 'numeric');
            app.Window1_step_cruise_speed.Limits = [0 50];
            app.Window1_step_cruise_speed.Value = 3;
            app.Window1_max_cruise_thrust = uieditfield(app.Panel2, 'numeric');
            app.Window1_max_cruise_thrust.Limits = [0 100];
            app.Window1_max_cruise_thrust.Value = 9;
            app.Window1_min_cruise_thrust = uieditfield(app.Panel2, 'numeric');
            app.Window1_min_cruise_thrust.Limits = [0 100];
            app.Window1_min_cruise_thrust.Value = 9;
            app.Window1_step_cruise_thrust = uieditfield(app.Panel2, 'numeric');
            app.Window1_step_cruise_thrust.Limits = [0 100];
            app.Window1_step_cruise_thrust.Value = 1;
            app.Window1_max_mass = uieditfield(app.Panel2, 'numeric');
            app.Window1_max_mass.Limits = [0 100];
            app.Window1_max_mass.Value = 9;
            app.Window1_min_mass = uieditfield(app.Panel2, 'numeric');
            app.Window1_min_mass.Limits = [0 100];
            app.Window1_min_mass.Value = 9;
            app.Window1_step_mass = uieditfield(app.Panel2, 'numeric');
            app.Window1_step_mass.Limits = [0 100];
            app.Window1_step_mass.Value = 1;
            app.Window1_max_ROC = uieditfield(app.Panel2, 'numeric');
            app.Window1_max_ROC.Limits = [0 50];
            app.Window1_max_ROC.Value = 4;
            app.Window1_min_ROC = uieditfield(app.Panel2, 'numeric');
            app.Window1_min_ROC.Limits = [0 50];
            app.Window1_min_ROC.Value = 4;
            app.Window1_step_ROC = uieditfield(app.Panel2, 'numeric');
            app.Window1_step_ROC.Limits = [0 50];
            app.Window1_step_ROC.Value = 1;
            app.Window1_max_ROC_speed = uieditfield(app.Panel2, 'numeric');
            app.Window1_max_ROC_speed.Limits = [-1 50];
            app.Window1_max_ROC_speed.Value = -1;
            app.Window1_min_ROC_speed = uieditfield(app.Panel2, 'numeric');
            app.Window1_min_ROC_speed.Limits = [-1 50];
            app.Window1_min_ROC_speed.Value = -1;
            app.Window1_step_ROC_speed = uieditfield(app.Panel2, 'numeric');
            app.Window1_step_ROC_speed.Limits = [-1 50];
            app.Window1_step_ROC_speed.Value = 1;
            app.Window1_max_ROC_drag = uieditfield(app.Panel2, 'numeric');
            app.Window1_max_ROC_drag.Limits = [-1 100];
            app.Window1_max_ROC_drag.Value = -1;
            app.Window1_min_ROC_drag = uieditfield(app.Panel2, 'numeric');
            app.Window1_min_ROC_drag.Limits = [-1 100];
            app.Window1_min_ROC_drag.Value = -1;
            app.Window1_step_ROC_drag = uieditfield(app.Panel2, 'numeric');
            app.Window1_step_ROC_drag.Limits = [0 100];
            app.Window1_step_ROC_drag.Value = 1;
            app.Step2SelectPropellerTab = uitab(app.TabGroup);
            app.Step2SelectPropellerTab.Title = 'Step 2 - Select Propeller';
            app.Window2_display_full_solution_set = uitable(app.Step2SelectPropellerTab);
            app.Window2_display_full_solution_set.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Window2_display_full_solution_set.RowName = {};
            app.Window2_display_full_solution_set.CellSelectionCallback = createCallbackFcn(app, @Window2_display_full_solution_setCellSelection, true);
            app.PleasechooseapropellerLabel = uilabel(app.Step2SelectPropellerTab);
            app.PleasechooseapropellerLabel.Text = 'Please choose a propeller';
            app.Step3SelectMotorTab = uitab(app.TabGroup);
            app.Step3SelectMotorTab.Title = 'Step 3 - Select Motor';
            app.Window3_table = uitable(app.Step3SelectMotorTab);
            app.Window3_table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Window3_table.RowName = {};
            app.Window3_table.CellSelectionCallback = createCallbackFcn(app, @Window3_tableCellSelection, true);
            app.PleaseChooseaMotorLabel = uilabel(app.Step3SelectMotorTab);
            app.PleaseChooseaMotorLabel.Text = 'Please Choose a Motor';
            app.Step4FullResultsTab = uitab(app.TabGroup);
            app.Step4FullResultsTab.Title = 'Step 4 - Full Results';
            app.DisplaybestfullresultLabel = uilabel(app.Step4FullResultsTab);
            app.DisplaybestfullresultLabel.Text = 'Display best full result, set to -1 and it will be reset to the value from your originally selected combination';
            app.InputParametersPanel = uipanel(app.Step4FullResultsTab);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.CellCount1Label = uilabel(app.InputParametersPanel);
            app.CellCount1Label.Text = 'Cell Count [1]';
            app.Window4_cell_count = uieditfield(app.InputParametersPanel, 'numeric');
            app.Window4_cell_count.ValueChangedFcn = createCallbackFcn(app, @Window4_cell_countValueChanged, true);
            app.Window4_cell_count.Limits = [-1 20];
            app.CruiseSpeedmsLabel = uilabel(app.InputParametersPanel);
            app.CruiseSpeedmsLabel.Text = 'Cruise Speed [m/s]';
            app.Window4_cruise_speed = uieditfield(app.InputParametersPanel, 'numeric');
            app.Window4_cruise_speed.ValueChangedFcn = createCallbackFcn(app, @Window4_cruise_speedValueChanged, true);
            app.Window4_cruise_speed.Limits = [-1 50];
            app.CruiseDragNLabel = uilabel(app.InputParametersPanel);
            app.CruiseDragNLabel.Text = 'Cruise Drag [N]';
            app.Window4_cruise_thrust = uieditfield(app.InputParametersPanel, 'numeric');
            app.Window4_cruise_thrust.ValueChangedFcn = createCallbackFcn(app, @Window4_cruise_thrustValueChanged, true);
            app.Window4_cruise_thrust.Limits = [-1 99];
            app.MasskgLabel = uilabel(app.InputParametersPanel);
            app.MasskgLabel.Text = 'Mass [kg]';
            app.Window4_mass = uieditfield(app.InputParametersPanel, 'numeric');
            app.Window4_mass.ValueChangedFcn = createCallbackFcn(app, @Window4_massValueChanged, true);
            app.Window4_mass.Limits = [-1 100];
            app.ROCmsLabel = uilabel(app.InputParametersPanel);
            app.ROCmsLabel.Text = 'ROC [m/s]';
            app.Window4_ROC = uieditfield(app.InputParametersPanel, 'numeric');
            app.Window4_ROC.ValueChangedFcn = createCallbackFcn(app, @Window4_ROCValueChanged, true);
            app.Window4_ROC.Limits = [-1 20];
            app.ROCVelocitymsLabel = uilabel(app.InputParametersPanel);
            app.ROCVelocitymsLabel.Text = 'ROC Velocity [m/s]';
            app.Window4_ROC_velocity = uieditfield(app.InputParametersPanel, 'numeric');
            app.Window4_ROC_velocity.ValueChangedFcn = createCallbackFcn(app, @Window4_ROC_velocityValueChanged, true);
            app.Window4_ROC_velocity.Limits = [-1 50];
            app.SelectedValuesPanel = uipanel(app.Step4FullResultsTab);
            app.SelectedValuesPanel.Title = 'Selected Values';
            app.PropDiaminLabel = uilabel(app.SelectedValuesPanel);
            app.PropDiaminLabel.Text = 'Prop Diam [in.]';
            app.Window4_Diam = uieditfield(app.SelectedValuesPanel, 'numeric');
            app.Window4_Diam.ValueChangedFcn = createCallbackFcn(app, @Window4_DiamValueChanged, true);
            app.Window4_Diam.Limits = [-1 35];
            app.PropPitchinLabel = uilabel(app.SelectedValuesPanel);
            app.PropPitchinLabel.Text = 'Prop Pitch [in.]';
            app.Window4_Pitch = uieditfield(app.SelectedValuesPanel, 'numeric');
            app.Window4_Pitch.ValueChangedFcn = createCallbackFcn(app, @Window4_PitchValueChanged, true);
            app.Window4_Pitch.Limits = [-1 35];
            app.MotorKvRPMVLabel = uilabel(app.SelectedValuesPanel);
            app.MotorKvRPMVLabel.Text = 'Motor Kv [RPM/V]';
            app.Window4_motor_Kv = uieditfield(app.SelectedValuesPanel, 'numeric');
            app.Window4_motor_Kv.ValueChangedFcn = createCallbackFcn(app, @Window4_motor_KvValueChanged, true);
            app.Window4_motor_Kv.Limits = [-1 8000];
            app.MotorMaxPowerWLabel = uilabel(app.SelectedValuesPanel);
            app.MotorMaxPowerWLabel.Text = 'Motor Max Power [W]';
            app.Window4_motor_max_power = uieditfield(app.SelectedValuesPanel, 'numeric');
            app.Window4_motor_max_power.ValueChangedFcn = createCallbackFcn(app, @Window4_motor_max_powerValueChanged, true);
            app.Window4_motor_max_power.Limits = [-1 20000];
            app.ESCRatingALabel = uilabel(app.SelectedValuesPanel);
            app.ESCRatingALabel.Text = 'ESC Rating [A]';
            app.Window4_ESC_rating = uieditfield(app.SelectedValuesPanel, 'numeric');
            app.Window4_ESC_rating.ValueChangedFcn = createCallbackFcn(app, @Window4_ESC_ratingValueChanged, true);
            app.Window4_ESC_rating.Limits = [-1 350];
            app.MotorEstimatedValuesPanel = uipanel(app.Step4FullResultsTab);
            app.MotorEstimatedValuesPanel.Title = 'Motor Estimated Values';
            app.MotorWeightgLabel = uilabel(app.MotorEstimatedValuesPanel);
            app.MotorWeightgLabel.Text = 'Motor Weight [g]';
            app.Window4_motor_weight = uieditfield(app.MotorEstimatedValuesPanel, 'numeric');
            app.Window4_motor_weight.ValueChangedFcn = createCallbackFcn(app, @Window4_motor_weightValueChanged, true);
            app.Window4_motor_weight.Limits = [-1 10000];
            app.MotorRestistanceOhmsLabel = uilabel(app.MotorEstimatedValuesPanel);
            app.MotorRestistanceOhmsLabel.Text = 'Motor Restistance [Ohms]';
            app.Window4_motor_resistance = uieditfield(app.MotorEstimatedValuesPanel, 'numeric');
            app.Window4_motor_resistance.ValueChangedFcn = createCallbackFcn(app, @Window4_motor_resistanceValueChanged, true);
            app.Window4_motor_resistance.Limits = [-1 5];
            app.MotorIdleCurrentALabel = uilabel(app.MotorEstimatedValuesPanel);
            app.MotorIdleCurrentALabel.Text = 'Motor Idle Current [A]';
            app.Window4_motor_idle_current = uieditfield(app.MotorEstimatedValuesPanel, 'numeric');
            app.Window4_motor_idle_current.ValueChangedFcn = createCallbackFcn(app, @Window4_motor_idle_currentValueChanged, true);
            app.Window4_motor_idle_current.Limits = [-1 15];
            app.CruiseValuesPanel = uipanel(app.Step4FullResultsTab);
            app.CruiseValuesPanel.Title = 'Cruise Values';
            app.nrevsLabel = uilabel(app.CruiseValuesPanel);
            app.nrevsLabel.Text = 'n [rev/s]';
            app.Window4_n_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_n_cruise.Editable = 'off';
            app.QNmLabel = uilabel(app.CruiseValuesPanel);
            app.QNmLabel.Text = 'Q [Nm]';
            app.Window4_Q_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_Q_cruise.Editable = 'off';
            app.ThrustNLabel = uilabel(app.CruiseValuesPanel);
            app.ThrustNLabel.Text = 'Thrust [N]';
            app.Window4_thrust_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_thrust_cruise.Editable = 'off';
            app.PropellerPowerWLabel = uilabel(app.CruiseValuesPanel);
            app.PropellerPowerWLabel.Text = 'Propeller Power [W]';
            app.Window4_power_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_power_cruise.Editable = 'off';
            app.InputMotorCurrentALabel = uilabel(app.CruiseValuesPanel);
            app.InputMotorCurrentALabel.Text = 'Input Motor Current [A]';
            app.Window4_I_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_I_cruise.Editable = 'off';
            app.InputMotorVoltageVLabel = uilabel(app.CruiseValuesPanel);
            app.InputMotorVoltageVLabel.Text = 'Input Motor Voltage [V]';
            app.Window4_V_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_V_cruise.Editable = 'off';
            app.InputMotorPowerWLabel = uilabel(app.CruiseValuesPanel);
            app.InputMotorPowerWLabel.Text = 'Input Motor Power [W]';
            app.Window4_I_V_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_I_V_cruise.Editable = 'off';
            app.MotorEfficiency1Label = uilabel(app.CruiseValuesPanel);
            app.MotorEfficiency1Label.Text = 'Motor Efficiency [1]';
            app.Window4_motor_eff_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_motor_eff_cruise.Editable = 'off';
            app.ESCEfficiency1Label = uilabel(app.CruiseValuesPanel);
            app.ESCEfficiency1Label.Text = 'ESC Efficiency [1]';
            app.Window4_ESC_eff_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_ESC_eff_cruise.Editable = 'off';
            app.TotalPowerUseWLabel = uilabel(app.CruiseValuesPanel);
            app.TotalPowerUseWLabel.Text = 'Total Power Use [W]';
            app.Window4_total_power_cruise = uieditfield(app.CruiseValuesPanel, 'numeric');
            app.Window4_total_power_cruise.Editable = 'off';
            app.ROCValuesPanel = uipanel(app.Step4FullResultsTab);
            app.ROCValuesPanel.Title = 'ROC Values';
            app.nrevsLabel_2 = uilabel(app.ROCValuesPanel);
            app.nrevsLabel_2.Text = 'n [rev/s]';
            app.Window4_n_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_n_ROC.Editable = 'off';
            app.QNmLabel_2 = uilabel(app.ROCValuesPanel);
            app.QNmLabel_2.Text = 'Q [Nm]';
            app.Window4_Q_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_Q_ROC.Editable = 'off';
            app.ThrustNLabel_2 = uilabel(app.ROCValuesPanel);
            app.ThrustNLabel_2.Text = 'Thrust [N]';
            app.Window4_thrust_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_thrust_ROC.Editable = 'off';
            app.PropellerPowerWLabel_2 = uilabel(app.ROCValuesPanel);
            app.PropellerPowerWLabel_2.Text = 'Propeller Power [W]';
            app.Window4_power_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_power_ROC.Editable = 'off';
            app.InputMotorCurrentALabel_2 = uilabel(app.ROCValuesPanel);
            app.InputMotorCurrentALabel_2.Text = 'Input Motor Current [A]';
            app.Window4_I_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_I_ROC.Editable = 'off';
            app.InputMotorVoltageVLabel_2 = uilabel(app.ROCValuesPanel);
            app.InputMotorVoltageVLabel_2.Text = 'Input Motor Voltage [V]';
            app.Window4_V_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_V_ROC.Editable = 'off';
            app.InputMotorPowerWLabel_2 = uilabel(app.ROCValuesPanel);
            app.InputMotorPowerWLabel_2.Text = 'Input Motor Power [W]';
            app.Window4_I_V_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_I_V_ROC.Editable = 'off';
            app.MotorEfficiency1Label_2 = uilabel(app.ROCValuesPanel);
            app.MotorEfficiency1Label_2.Text = 'Motor Efficiency [1]';
            app.Window4_motor_eff_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_motor_eff_ROC.Editable = 'off';
            app.ESCEfficiency1Label_2 = uilabel(app.ROCValuesPanel);
            app.ESCEfficiency1Label_2.Text = 'ESC Efficiency [1]';
            app.Window4_ESC_eff_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_ESC_eff_ROC.Editable = 'off';
            app.TotalPowerUseWLabel_2 = uilabel(app.ROCValuesPanel);
            app.TotalPowerUseWLabel_2.Text = 'Total Power Use [W]';
            app.Window4_total_power_ROC = uieditfield(app.ROCValuesPanel, 'numeric');
            app.Window4_total_power_ROC.Editable = 'off';
            app.ComparetochosenvaluesPanel = uipanel(app.Step4FullResultsTab);
            app.ComparetochosenvaluesPanel.Title = 'Compare to chosen values';
            app.PowerUseofSelectedSystemWLabel = uilabel(app.ComparetochosenvaluesPanel);
            app.PowerUseofSelectedSystemWLabel.Text = 'Power Use of Selected System [W]';
            app.Window4_total_power_old = uieditfield(app.ComparetochosenvaluesPanel, 'numeric');
            app.Window4_total_power_old.Editable = 'off';
            app.EfficiencyofSelectedSystem1Label = uilabel(app.ComparetochosenvaluesPanel);
            app.EfficiencyofSelectedSystem1Label.Text = 'Efficiency of Selected System [1]';
            app.Window4_total_eff_old = uieditfield(app.ComparetochosenvaluesPanel, 'numeric');
            app.Window4_total_eff_old.Editable = 'off';
            app.PowerUseofEditedSystemWLabel = uilabel(app.ComparetochosenvaluesPanel);
            app.PowerUseofEditedSystemWLabel.Text = 'Power Use of Edited System [W]';
            app.Window4_total_power_new = uieditfield(app.ComparetochosenvaluesPanel, 'numeric');
            app.Window4_total_power_new.Editable = 'off';
            app.EfficiencyofEditedSystem1Label = uilabel(app.ComparetochosenvaluesPanel);
            app.EfficiencyofEditedSystem1Label.Text = 'Efficiency of Edited System [1]';
            app.Window4_total_eff_new = uieditfield(app.ComparetochosenvaluesPanel, 'numeric');
            app.Window4_total_eff_new.Editable = 'off';
            app.Window4_check_valid = uieditfield(app.Step4FullResultsTab, 'numeric');
            app.Window4_check_valid.Editable = 'off';
            app.RedoButton = uibutton(app.Step4FullResultsTab, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.Panel3 = uipanel(app.Step4FullResultsTab);
            app.Panel3.Title = 'Panel3';
            app.BatteryCapacityAhLabel = uilabel(app.Panel3);
            app.BatteryCapacityAhLabel.Text = 'Battery Capacity [Ah]';
            app.Window4_capacity = uieditfield(app.Panel3, 'numeric');
            app.Window4_capacity.ValueChangedFcn = createCallbackFcn(app, @Window4_capacityValueChanged, true);
            app.AvionicsConstantAveragePowerUseWLabel = uilabel(app.Panel3);
            app.AvionicsConstantAveragePowerUseWLabel.Text = 'Avionics Constant Average Power Use [W]';
            app.Window4_avionics_power = uieditfield(app.Panel3, 'numeric');
            app.Window4_avionics_power.ValueChangedFcn = createCallbackFcn(app, @Window4_avionics_powerValueChanged, true);
            app.TimespentinClimbminsLabel = uilabel(app.Panel3);
            app.TimespentinClimbminsLabel.Text = 'Time spent in Climb [mins]';
            app.Window4_time_ROC = uieditfield(app.Panel3, 'numeric');
            app.Window4_time_ROC.ValueChangedFcn = createCallbackFcn(app, @Window4_time_ROCValueChanged, true);
            app.Window4_time_ROC.Editable = 'off';
            app.TimespentinCruiseminsLabel = uilabel(app.Panel3);
            app.TimespentinCruiseminsLabel.Text = 'Time spent in Cruise [mins]';
            app.Window4_time_cruise = uieditfield(app.Panel3, 'numeric');
            app.Window4_time_cruise.Editable = 'off';
            app.TotaldistancetravelledkmLabel = uilabel(app.Panel3);
            app.TotaldistancetravelledkmLabel.Text = 'Total distance travelled [km]';
            app.Window4_distance_travelled = uieditfield(app.Panel3, 'numeric');
            app.Window4_distance_travelled.Editable = 'off';
            app.BatteryCapacityEfficiencyFactorLabel = uilabel(app.Panel3);
            app.BatteryCapacityEfficiencyFactorLabel.Text = 'Battery Capacity Efficiency Factor';
            app.Window4_battery_eff = uieditfield(app.Panel3, 'numeric');
            app.Window4_battery_eff.Limits = [0 1];
            app.Window4_battery_eff.Editable = 'off';
            app.Window4_battery_eff.Value = 0.9;
            app.PredictMotorRmCheckBox = uicheckbox(app.Step4FullResultsTab);
            app.PredictMotorRmCheckBox.Text = 'Predict Motor Rm';
            app.PredictMotorRmCheckBox.Value = true;
            app.PredictMotorIoCheckBox = uicheckbox(app.Step4FullResultsTab);
            app.PredictMotorIoCheckBox.Text = 'Predict Motor Io';
            app.PredictMotorIoCheckBox.Value = true;
            app.CheckMaxpowervsthemotorratedmaximumpowerLabel = uilabel(app.Step4FullResultsTab);
            app.CheckMaxpowervsthemotorratedmaximumpowerLabel.Text = 'Check Max power vs the motor rated maximum power';
            app.UpdateRangeEstimateLabel = uilabel(app.Step4FullResultsTab);
            app.UpdateRangeEstimateLabel.Text = 'Update Range Estimate';
            app.PredictMotorWeightCheckBox = uicheckbox(app.Step4FullResultsTab);
            app.PredictMotorWeightCheckBox.Text = 'Predict Motor Weight';
            app.PredictMotorWeightCheckBox.Value = true;
            app.Step5GraphsofchangeineffeciencyTab = uitab(app.TabGroup);
            app.Step5GraphsofchangeineffeciencyTab.Title = 'Step 5 - Graphs of change in effeciency';
            app.OverridesTab = uitab(app.TabGroup);
            app.OverridesTab.Title = 'Over-rides';
            app.Label_2 = uilabel(app.OverridesTab);
            app.Label_2.Text = 'It is not recommended to override any values here as they are good to avoid systems attempting to operate outside the allowable conditions';
            app.Progress_panel = uipanel(app.Motor_Prop_Calc);
            app.progress_label = uilabel(app.Progress_panel);
            app.progress_label.Text = 'Progress:';
            app.progress_percent = uilabel(app.Progress_panel);
            app.progress_percent.Text = '0%';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trainer_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Motor_Prop_Calc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Motor_Prop_Calc)
        end
    end
end